classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SubjectNumberEditFieldLabel    matlab.ui.control.Label
        SubjectNumberEditField         matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        BlockSegmentationAlgorithmPanel  matlab.ui.container.Panel
        NumberofColumnsEditField       matlab.ui.control.NumericEditField
        NumberofColumnsEditFieldLabel  matlab.ui.control.Label
        HumomentsButton                matlab.ui.control.Button
        NumberofRowsEditField          matlab.ui.control.NumericEditField
        NumberofRowsEditFieldLabel     matlab.ui.control.Label
        StatisticsAlgorithmsPanel      matlab.ui.container.Panel
        EigenearsButton                matlab.ui.control.Button
        QvectorButton                  matlab.ui.control.Button
        MeasurementMatchButton         matlab.ui.control.Button
        ResultsPanel                   matlab.ui.container.Panel
        MatchedImage                   matlab.ui.control.Image
        QueryImageLabel                matlab.ui.control.Label
        MatchedClassLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        EARMATCHINGLabel               matlab.ui.control.Label
        DataAnalysisPanel              matlab.ui.container.Panel
        DataImage                      matlab.ui.control.Image
        ImageAquisitionPanel           matlab.ui.container.Panel
        BrowseImageButton              matlab.ui.control.Button
        TakeImageButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        EditRows
        EditCols% Description
        earDatabase
        Property4 % Description
    end
    
    methods (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MeasurementMatchButton
        function MeasurementMatchButtonPushed(app, event)
            earDatabase=imageSet('cpic_right',"recursive");
            
            h = waitbar(0,'Please wait...');
            steps = 1000;
            for step = 1:steps/2
                  waitbar(step / steps)
            end

            
            [subj_num,min_index]=measurement_matchtest;
            
            for step = steps/2: steps
             waitbar(step / steps)
            end
            pause(2)
            close(h)

            app.SubjectNumberEditField.Value=subj_num;
            app.MatchedImage.ImageSource = read(earDatabase,min_index);
                
        end

        % Button pushed function: HumomentsButton
        function HumomentsButtonPushed(app, event)
            %galleryImage=imread('0148_right.jpg');
%             galleryImage=imread(app.QueryImage.ImageSource)
            global galleryImage;
            earDatabase=imageSet('cpic_right',"recursive");
            numRows= app.EditRows;
            numCols= app.EditCols;
            segment_image;
            app.DataImage.ImageSource =imread('segmentedEar.jpg');
            
            h = waitbar(0,'Please wait...');
            steps = 1000;
            for step = 1:steps/2
                  waitbar(step / steps);
            end
            
            notnormalized_sepim;
            
            for step = steps/2: steps
             waitbar(step / steps);
            end
            pause(2);
            close(h);
            
            app.SubjectNumberEditField.Value=match_num;
            app.MatchedImage.ImageSource = read(earDatabase,index);
        end

        % Button pushed function: EigenearsButton
        function EigenearsButtonPushed(app, event)
%             galleryImage=imread(app.QueryImage.ImageSource);
            global galleryImage;
            subj_num=eigenears(galleryImage);
            app.SubjectNumberEditField.Value=subj_num;
        end

        % Button pushed function: QvectorButton
        function QvectorButtonPushed(app, event)
%             galleryImage=imread(app.QueryImage.ImageSource)
            global galleryImage;
            earDatabase=imageSet('cpic_right',"recursive");
            
             h = waitbar(0,'Please wait...');
            steps = 1000;
            for step = 1:steps/2
                  waitbar(step / steps);
            end
            
            HOGIx;
            
            for step = steps/2: steps
             waitbar(step / steps);
            end
            pause(2);
            close(h);
            
            app.SubjectNumberEditField.Value=q;
            app.MatchedImage.ImageSource = read(earDatabase,x);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SubjectNumberEditField.Value=0;
            app.NumberofColumnsEditField.Value=0;
            app.NumberofRowsEditField.Value=0;
            app.MatchedImage.ImageSource =imread('Screen Shot 2020-08-24 at 12.59.43 AM (2).png');
             app.DataImage.ImageSource =imread('Screen Shot 2020-08-24 at 12.59.43 AM (2).png');
%             imshow('Screen Shot 2020-08-24 at 12.59.43 AM (2).png','Parent', app.UIAxes);
        end

        % Value changed function: NumberofColumnsEditField
        function NumberofColumnsEditFieldValueChanged(app, event)
            value = app.NumberofColumnsEditField.Value;
            app.EditCols = value;
        end

        % Value changed function: NumberofRowsEditField
        function NumberofRowsEditFieldValueChanged(app, event)
            value = app.NumberofRowsEditField.Value;
            app.EditRows = value;
        end

        % Callback function
        function NumberofRowsEditFieldValueChanged2(app, event)
        value = app.NumberofRowsEditField.Value;
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
global galleryImage;
            [filename, pathname] = uigetfile({'*.jpg'; '*.bmp'; '*.gif'; '*.*'}, 'Pick an image file');
            galleryImage = strcat(pathname, filename);
            galleryImage = imread(galleryImage);
            imshow(galleryImage,'Parent', app.UIAxes);
        end

        % Button pushed function: TakeImageButton
        function TakeImageButtonPushed(app, event)
        uiwait(msgbox('Place the webcam of your computer at eye level then turn 90 degrees. Once you click ok you will have 100 frames to capture the image. Afterwards place a rectange around the ear and right click to crop.','Instructions','modal'));
        galleryImage=facedetection;
        imshow(galleryImage,'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SubjectNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectNumberEditFieldLabel.Text = 'Subject Number';
            app.SubjectNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BlockSegmentationAlgorithmPanel = uipanel(app.UIFigure);
            app.BlockSegmentationAlgorithmPanel.Title = 'Block Segmentation Algorithm';
            app.NumberofColumnsEditField = uieditfield(app.BlockSegmentationAlgorithmPanel, 'numeric');
            app.NumberofColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofColumnsEditFieldValueChanged, true);
            app.NumberofColumnsEditFieldLabel = uilabel(app.BlockSegmentationAlgorithmPanel);
            app.NumberofColumnsEditFieldLabel.Text = 'Number of Columns';
            app.HumomentsButton = uibutton(app.BlockSegmentationAlgorithmPanel, 'push');
            app.HumomentsButton.ButtonPushedFcn = createCallbackFcn(app, @HumomentsButtonPushed, true);
            app.HumomentsButton.Text = 'Hu moments';
            app.NumberofRowsEditField = uieditfield(app.BlockSegmentationAlgorithmPanel, 'numeric');
            app.NumberofRowsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRowsEditFieldValueChanged, true);
            app.NumberofRowsEditFieldLabel = uilabel(app.BlockSegmentationAlgorithmPanel);
            app.NumberofRowsEditFieldLabel.Text = 'Number of Rows';
            app.StatisticsAlgorithmsPanel = uipanel(app.UIFigure);
            app.StatisticsAlgorithmsPanel.Title = 'Statistics Algorithms';
            app.EigenearsButton = uibutton(app.StatisticsAlgorithmsPanel, 'push');
            app.EigenearsButton.ButtonPushedFcn = createCallbackFcn(app, @EigenearsButtonPushed, true);
            app.EigenearsButton.Text = 'Eigenears';
            app.QvectorButton = uibutton(app.StatisticsAlgorithmsPanel, 'push');
            app.QvectorButton.ButtonPushedFcn = createCallbackFcn(app, @QvectorButtonPushed, true);
            app.QvectorButton.Text = 'Q-vector';
            app.MeasurementMatchButton = uibutton(app.UIFigure, 'push');
            app.MeasurementMatchButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurementMatchButtonPushed, true);
            app.MeasurementMatchButton.Text = 'Measurement Match';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.MatchedImage = uiimage(app.ResultsPanel);
            app.MatchedImage.ImageSource = 'Screen Shot 2020-08-24 at 12.59.43 AM (2).png';
            app.QueryImageLabel = uilabel(app.ResultsPanel);
            app.QueryImageLabel.Text = 'Query Image';
            app.MatchedClassLabel = uilabel(app.ResultsPanel);
            app.MatchedClassLabel.Text = 'Matched Class';
            app.UIAxes = uiaxes(app.ResultsPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.EARMATCHINGLabel = uilabel(app.UIFigure);
            app.EARMATCHINGLabel.Text = 'EAR MATCHING';
            app.DataAnalysisPanel = uipanel(app.UIFigure);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.DataImage = uiimage(app.DataAnalysisPanel);
            app.DataImage.ImageSource = 'Screen Shot 2020-08-24 at 12.59.43 AM (2).png';
            app.ImageAquisitionPanel = uipanel(app.UIFigure);
            app.ImageAquisitionPanel.Title = 'Image Aquisition ';
            app.BrowseImageButton = uibutton(app.ImageAquisitionPanel, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.TakeImageButton = uibutton(app.ImageAquisitionPanel, 'push');
            app.TakeImageButton.ButtonPushedFcn = createCallbackFcn(app, @TakeImageButtonPushed, true);
            app.TakeImageButton.Text = 'Take Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end